classdef blackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        HitButton    matlab.ui.control.Button
        StandButton  matlab.ui.control.Button
        DeckBack     matlab.ui.control.Image
    end

    
    properties (Access = public)
        deck % Description
    end
    
    methods (Access = public)
        
        function cardImage = newCard(app, deck)
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [200 211 200 200];
            if app.deck.p1Hand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.p1Hand.cards(end).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.p1Hand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.p1Hand.cards(end).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            import pkg.Deck.*
            import pkg.Card.*
            import pkg.Hand.*
            import pkg.DiscardPile.*
            
            app.deck = Deck;
        end

        % Callback function
        function CloseRequestFcn(app, event)
delete(app)

        end

        % Button pushed function: HitButton
        function hitMe(app, event)
            app.deck.p1Hand.cards = [app.deck.p1Hand.cards, app.deck.cards(1)];
            app.deck.p1Hand.cards(end).privacy = 1;
            app.deck.cards(1) = [];
            app.deck.p1Hand.cards(end).imageApp = newCard(app, app.deck);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @hitMe, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.Text = 'Stand';
            app.DeckBack = uiimage(app.UIFigure);
            app.DeckBack.ImageSource = 'back.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end